classdef IRB_6700_Controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TransferButton          matlab.ui.control.Button
        TextArea_18             matlab.ui.control.TextArea
        TextArea_17             matlab.ui.control.TextArea
        TextArea_16             matlab.ui.control.TextArea
        SpinDisp                matlab.ui.control.NumericEditField
        NutationDisp            matlab.ui.control.NumericEditField
        PrecessionDisp          matlab.ui.control.NumericEditField
        TextArea_15             matlab.ui.control.TextArea
        TextArea_14             matlab.ui.control.TextArea
        TextArea_13             matlab.ui.control.TextArea
        TextArea_12             matlab.ui.control.TextArea
        TextArea_11             matlab.ui.control.TextArea
        TextArea_10             matlab.ui.control.TextArea
        Spin                    matlab.ui.control.NumericEditField
        Nutation                matlab.ui.control.NumericEditField
        Precession              matlab.ui.control.NumericEditField
        Zinput                  matlab.ui.control.NumericEditField
        Yinput                  matlab.ui.control.NumericEditField
        Xinput                  matlab.ui.control.NumericEditField
        INVERSEKINEMATICButton  matlab.ui.control.Button
        DEFAULTTOZEROButton     matlab.ui.control.Button
        STOPButton              matlab.ui.control.Button
        RUNButton               matlab.ui.control.Button
        TextArea_9              matlab.ui.control.TextArea
        TextArea_8              matlab.ui.control.TextArea
        TextArea_7              matlab.ui.control.TextArea
        TextArea_6              matlab.ui.control.TextArea
        TextArea_5              matlab.ui.control.TextArea
        TextArea_4              matlab.ui.control.TextArea
        TextArea_3              matlab.ui.control.TextArea
        TextArea_2              matlab.ui.control.TextArea
        TextArea                matlab.ui.control.TextArea
        ForwardPz               matlab.ui.control.NumericEditField
        ForwardPy               matlab.ui.control.NumericEditField
        ForwardPx               matlab.ui.control.NumericEditField
        Theta6_Field            matlab.ui.control.NumericEditField
        Theta6_Slider           matlab.ui.control.Slider
        Theta5_Field            matlab.ui.control.NumericEditField
        Theta5_Slider           matlab.ui.control.Slider
        Theta4_Field            matlab.ui.control.NumericEditField
        Theta4_Slider           matlab.ui.control.Slider
        Theta3_Field            matlab.ui.control.NumericEditField
        Theta3_Slider           matlab.ui.control.Slider
        Theta2_Field            matlab.ui.control.NumericEditField
        Theta2_Slider           matlab.ui.control.Slider
        Theta1_Field            matlab.ui.control.NumericEditField
        Theta1_Slider           matlab.ui.control.Slider
    end

    
    methods (Access = public)
        
        function result = ForwardKinematic(app)
            theta1 = app.Theta1_Slider.Value * pi / 180;
            theta2 = - app.Theta2_Slider.Value * pi / 180 + pi / 2; 
            theta3 = app.Theta3_Slider.Value * pi / 180;
            theta4 = app.Theta4_Slider.Value * pi / 180;
            theta5 = -app.Theta5_Slider.Value * pi / 180;
            theta6 = app.Theta6_Slider.Value * pi / 180;

            T1 = [[cos(theta1)      0       sin(theta1)     320 * cos(theta1)]
                  [sin(theta1)      0       -cos(theta1)    320 * sin(theta1)]
                  [0                1       0               780              ]
                  [0                0       0               1                ]];

            T2 = [[cos(theta2)      sin(theta2)     0       1280 * cos(theta2)]
                  [sin(theta2)      -cos(theta2)    0       1280 * sin(theta2)]
                  [0                0               -1      0                 ]
                  [0                0               0       1                 ]];

            T3 = [[cos(theta3)      0       sin(theta3)     200 * cos(theta3)]
                  [sin(theta3)      0       -cos(theta3)    200 * sin(theta3)]
                  [0                1       0               0                ]
                  [0                0       0               1                ]];

            T4 = [[cos(theta4)      0       sin(theta4)     0 * cos(theta4)  ]
                  [sin(theta4)      0       -cos(theta4)    0 * sin(theta4)  ]
                  [0                1       0               -1592.5          ]
                  [0                0       0               1                ]];

            T5 = [[cos(theta5)      0       sin(theta5)     0 * cos(theta5)   ]
                  [sin(theta5)      0       -cos(theta5)    0 * cos(theta5)   ]
                  [0                1       0               0                 ]
                  [0                0       0               1                 ]];
            
            T6 = [[cos(theta6)      -sin(theta6)    0       0 * cos(theta6)   ]
                  [sin(theta6)      cos(theta6)     0       0 * cos(theta6)   ]
                  [0                0               1       200               ]
                  [0                0               0       1                 ]];


            T06 = T1 * T2 * T3 * T4 * T5 * T6;
            fixError = zeros(size(T06));
            fixError(abs(T06) > 1e-6) = 1;
            T06 = T06 .* fixError;

            pos =  T06 * [0; 0; 0; 1];
            app.ForwardPx.Value = pos(1, 1);
            app.ForwardPy.Value = pos(2, 1);
            app.ForwardPz.Value = pos(3, 1);

            theta = atan2(sqrt(T06(3, 1) ^ 2 + T06(3, 2) ^ 2), T06(3,3));
            psi = atan2(T06(1, 3), T06(2, 3));
            phi = atan2(T06(3, 1), -T06(3, 2));
            
            app.PrecessionDisp.Value = phi;
            app.NutationDisp.Value = theta;
            app.SpinDisp.Value = psi;
            
            result = {pos, phi, theta, psi};
   
        end

        function InverseKinematic(app, Xtarget, Ytarget, Ztarget, phi, theta, psi)

            R06 = [cos(phi) * cos(psi) - cos(theta) * sin(phi) * sin(psi)      sin(phi) * cos(psi) + cos(theta) * cos(phi) * sin(psi)      sin(theta) * sin(psi);
                   -cos(phi) * sin(psi) - cos(theta) * sin(phi) * cos(psi)     -sin(phi) * sin(psi) + cos(theta) * cos(phi) * cos(psi)     sin(theta) * cos(psi);
                   sin(theta) * sin(phi)                                       -cos(phi) * sin(theta)                                      cos(theta)           ];

            fixError = zeros(size(R06));
            fixError(abs(R06) > 1e-6) = 1;
            R06 = R06 .* fixError;
                
            XYZtarget = [Xtarget Ytarget Ztarget]' - R06 * [0 0 200]';
            X = XYZtarget(1); Y = XYZtarget(2); Z = XYZtarget(3);

            theta1_val = atan2(Y, X);

            D0 = 780;
            L1 = 320;
            L2 = 1280;
            L3 = 200;
            D1 = 1592.5;
            
            beta = atan2(D1, L3);
            sigma = atan2(Z - D0, sqrt(X^2 + Y^2) - L1);
            
            BW = sqrt((Z - D0) ^ 2 + (sqrt(X^2 + Y^2) - L1) ^ 2);
            CB = L2;
            CW = sqrt(L3^2 + D1^2);
            
            cosphi = (BW^2 + CB^2 - CW^2) / (2 * BW * CB);
            phi1 = atan2(sqrt(1 - cosphi^2), cosphi);
            phi2 = atan2(-sqrt(1 - cosphi^2), cosphi);
            
            theta2_val1 = sigma - phi1
            theta2_val2 = sigma - phi2
            
            cosgamma = -(CB^2 + CW^2 - BW^2) / (2 * CB * CW);
            gamma1 = atan2(sqrt(1 - cosgamma^2), cosgamma);
            gamma2 = atan2(-sqrt(1 - cosgamma^2), cosgamma);
            
            theta3_val1 = -(gamma1 + beta)
            theta3_val2 = -(gamma2 + beta)
            
            if (theta2_val1 <= rad2deg(155) && theta2_val1 >= rad2deg(5) && theta3_val1 >= rad2deg(-180) && theta3_val1 <= rad2deg(70))
                theta2_val = theta2_val1;
                theta3_val = theta3_val1;
            else
                theta2_val = theta2_val2;
                theta3_val = theta3_val2;
            end
            
            T1 = [[cos(theta1_val)      0       sin(theta1_val)     320 * cos(theta1_val)]
                  [sin(theta1_val)      0       -cos(theta1_val)    320 * sin(theta1_val)]
                  [0                    1       0                   780                  ]
                  [0                    0       0                   1                    ]];

            T2 = [[cos(theta2_val)      sin(theta2_val)     0       1280 * cos(theta2_val)]
                  [sin(theta2_val)      -cos(theta2_val)    0       1280 * sin(theta2_val)]
                  [0                    0                   -1      0                     ]
                  [0                    0                   0       1                     ]];

            T3 = [[cos(theta3_val)      0       sin(theta3_val)     200 * cos(theta3_val)]
                  [sin(theta3_val)      0       -cos(theta3_val)    200 * sin(theta3_val)]
                  [0                    1       0                   0                    ]
                  [0                    0       0                   1                    ]];
            
            T03 = double(T1 * T2 * T3);
            inverseR03 = inv(T03(1:3, 1:3));
            R04 = inverseR03 * R06;


            % Fix for if the value is bullshitting with me. AKA -0.0000
            fixError = zeros(size(R04));
            fixError(abs(R04) > 1e-6) = 1;
            R04 = R04 .* fixError;
            
            theta4_val = atan2(R04(2,3), R04(1,3));
            theta5_val = atan2(sqrt(R04(1, 3)^2 + R04(2, 3)^2), -R04(3, 3));
            theta6_val = atan2(-R04(3,2), R04(3,1));

            values = [theta1_val * 180 / pi 
                      -(theta2_val * 180 / pi - 90) 
                      theta3_val * 180 / pi 
                      theta4_val * 180 / pi 
                      -theta5_val * 180 / pi  
                      theta6_val * 180 / pi ];
            sliderFields = {app.Theta1_Slider, app.Theta2_Slider, app.Theta3_Slider, ...
                            app.Theta4_Slider, app.Theta5_Slider, app.Theta6_Slider};
            numericFields = {app.Theta1_Field, app.Theta2_Field, app.Theta3_Field, ...
                             app.Theta4_Field, app.Theta5_Field, app.Theta6_Field};

                        
            app.MultipleGUIControl(sliderFields, numericFields, values);
        end

        function MultipleGUIControl(app, sliderFields, numericFields, targetValue)
            for i = 1:length(sliderFields)
                currentSlider = sliderFields{i};
                currentValue = currentSlider.Value;
                step = sign(targetValue(i) - currentValue) * 1; 
                
                while abs(currentValue - targetValue(i)) >= 1
                    currentValue = currentValue + step;
                    if abs(currentValue) < abs(step)
                        currentValue = 0; 
                    end
                    
                    % Update both the slider and the numeric field
                    currentSlider.Value = currentValue;
                    numericFields{i}.Value = currentValue;
                    if numericFields{i}.Value == numericFields{i}.Limits(1) || numericFields{i}.Value == numericFields{i}.Limits(2)
                        break
                    end
                    
                    % Update the Simulink model parameter
                    if i == 3 || i == 5
                        set_param(strcat("IRB_6700/SliderGain", num2str(i)), 'Gain', num2str(-currentValue));
                    else
                        set_param(strcat("IRB_6700/SliderGain", num2str(i)), 'Gain', num2str(currentValue));
                    end
                    
                    % Redraw GUI and introduce a delay for visibility
                    drawnow;
                    pause(0.003); % Adjust the delay to control the speed of resetting
                    app.ForwardKinematic();
                end
            end
        end

        function SlowUpdate(app, Sliderval, targetValue, i)
            currentSlider = Sliderval;
            currentValue = currentSlider.Value;
            step = sign(targetValue - currentValue) * 1;
        
            % Use a while loop to adjust currentValue towards targetValue
            while abs(currentValue - targetValue) >= 1 % Adjust to ensure smooth transition
                currentValue = currentValue + step;
                
                % Update both the slider and the numeric field
                currentSlider.Value = currentValue;
                
                % Update the Simulink model parameter
                if i == 3 || i == 5
                    set_param(strcat("IRB_6700/SliderGain", num2str(i)), 'Gain', num2str(-currentValue));
                else
                    set_param(strcat("IRB_6700/SliderGain", num2str(i)), 'Gain', num2str(currentValue));
                end
                
                % Redraw GUI and introduce a delay for visibility
                drawnow;
                pause(0.01); % Adjusted for visibility
                app.ForwardKinematic(); % Recalculate angles and update the display
            end
        
            % Ensure the final values match exactly
            currentSlider.Value = targetValue;
            if i == 3 || i == 5
                set_param(strcat("IRB_6700/SliderGain", num2str(i)), 'Gain', num2str(-targetValue));
            else
                set_param(strcat("IRB_6700/SliderGain", num2str(i)), 'Gain', num2str(targetValue));
            end
            app.ForwardKinematic(); % Final update to ensure consistency
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: Theta1_Field
        function Theta1_FieldValueChanged(app, event)
            value = app.Theta1_Field.Value;
            % Check if the value is outside the limits and adjust it if necessary
            if value < app.Theta1_Slider.Limits(1)
                value = app.Theta1_Slider.Limits(1); % Set to minimum limit
            elseif value > app.Theta1_Slider.Limits(2)
                value = app.Theta1_Slider.Limits(2); % Set to maximum limit
            end
            app.Theta1_Field.Value = value;
            % Set the slider value
            app.SlowUpdate(app.Theta1_Slider, value, 1)

        end

        % Value changing function: Theta1_Slider
        function Theta1_SliderValueChanging(app, event)
            changingValue = event.Value;
            theta = changingValue;
            app.Theta1_Field.Value = round(theta, 2);

            set_param("IRB_6700/SliderGain1", 'gain', num2str(theta));
            app.ForwardKinematic();
        end

        % Value changed function: Theta2_Field
        function Theta2_FieldValueChanged(app, event)
            value = app.Theta2_Field.Value;
            % Check if the value is outside the limits and adjust it if necessary
            if value < app.Theta2_Slider.Limits(1)
                value = app.Theta2_Slider.Limits(1); % Set to minimum limit
            elseif value > app.Theta2_Slider.Limits(2)
                value = app.Theta2_Slider.Limits(2); % Set to maximum limit
            end
            app.Theta2_Field.Value = value;
            % Set the slider value
            app.SlowUpdate(app.Theta2_Slider, value, 2)

        end

        % Value changing function: Theta2_Slider
        function Theta2_SliderValueChanging(app, event)
            changingValue = event.Value;
            theta = changingValue;
            app.Theta2_Field.Value = round(theta, 2);
            
            set_param("IRB_6700/SliderGain2", 'gain', num2str(theta));
            app.ForwardKinematic();
        end

        % Value changed function: Theta3_Field
        function Theta3_FieldValueChanged(app, event)
            value = app.Theta3_Field.Value;
            % Check if the value is outside the limits and adjust it if necessary
            if value < app.Theta3_Slider.Limits(1)
                value = app.Theta3_Slider.Limits(1); % Set to minimum limit
            elseif value > app.Theta3_Slider.Limits(2)
                value = app.Theta3_Slider.Limits(2); % Set to maximum limit
            end
            app.Theta3_Field.Value = value;
            % Set the slider value
            app.SlowUpdate(app.Theta3_Slider, value, 3)

        end

        % Value changing function: Theta3_Slider
        function Theta3_SliderValueChanging(app, event)
            changingValue = event.Value;
            theta = changingValue;
            app.Theta3_Field.Value = round(theta, 2);

            % WARNING: THETA 3 ANGLE ARE REVERSED

            set_param("IRB_6700/SliderGain3", 'gain', num2str(-theta));
            app.ForwardKinematic();
        end

        % Value changed function: Theta4_Field
        function Theta4_FieldValueChanged(app, event)
            value = app.Theta4_Field.Value;
            % Check if the value is outside the limits and adjust it if necessary
            if value < app.Theta4_Slider.Limits(1)
                value = app.Theta4_Slider.Limits(1); % Set to minimum limit
            elseif value > app.Theta4_Slider.Limits(2)
                value = app.Theta4_Slider.Limits(2); % Set to maximum limit
            end
            app.Theta4_Field.Value = value;
            % Set the slider value
            app.SlowUpdate(app.Theta4_Slider, value, 4)

        end

        % Value changing function: Theta4_Slider
        function Theta4_SliderValueChanging(app, event)
            changingValue = event.Value;
            theta = changingValue;
            app.Theta4_Field.Value = round(theta, 2);
            set_param("IRB_6700/SliderGain4", 'gain', num2str(theta));
            app.ForwardKinematic();
        end

        % Value changed function: Theta5_Field
        function Theta5_FieldValueChanged(app, event)
            value = app.Theta5_Field.Value;
            % Check if the value is outside the limits and adjust it if necessary
            if value < app.Theta5_Slider.Limits(1)
                value = app.Theta5_Slider.Limits(1); % Set to minimum limit
            elseif value > app.Theta5_Slider.Limits(2)
                value = app.Theta5_Slider.Limits(2); % Set to maximum limit
            end
            app.Theta5_Field.Value = value;
            % Set the slider value
            app.SlowUpdate(app.Theta5_Slider, value, 5)
        end

        % Value changing function: Theta5_Slider
        function Theta5_SliderValueChanging(app, event)
            changingValue = event.Value;
            theta = changingValue;
            app.Theta5_Field.Value = round(theta, 2);
            set_param("IRB_6700/SliderGain5", 'gain', num2str(-theta));
            app.ForwardKinematic();
        end

        % Value changed function: Theta6_Field
        function Theta6_FieldValueChanged(app, event)
            value = app.Theta6_Field.Value;
            % Check if the value is outside the limits and adjust it if necessary
            if value < app.Theta6_Slider.Limits(1)
                value = app.Theta6_Slider.Limits(1); % Set to minimum limit
            elseif value > app.Theta6_Slider.Limits(2)
                value = app.Theta6_Slider.Limits(2); % Set to maximum limit
            end
            app.Theta6_Field.Value = value;
            % Set the slider value
            app.SlowUpdate(app.Theta6_Slider, value, 6)
        end

        % Value changing function: Theta6_Slider
        function Theta6_SliderValueChanging(app, event)
            changingValue = event.Value;
            theta = changingValue;
            app.Theta6_Field.Value = round(theta, 2);
            set_param("IRB_6700/SliderGain6", 'gain', num2str(theta));
            app.ForwardKinematic();
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            ModelName = "IRB_6700";
            set_param(ModelName, 'BlockReduction', 'off');
            set_param(ModelName, 'StopTime', 'inf')
            set_param(ModelName, "simulationMode", 'normal');

            set_param(ModelName, 'StartFcn', '1');

            set_param(ModelName, 'SimulationCommand', 'start');
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            ModelName = "IRB_6700";
            set_param(ModelName, 'StartFcn', '0');
            set_param(ModelName, 'SimulationCommand', 'stop');
            
            close;
        end

        % Button pushed function: DEFAULTTOZEROButton
        function DEFAULTTOZEROButtonPushed(app, event)


            sliderFields = {app.Theta1_Slider, app.Theta2_Slider, app.Theta3_Slider, ...
                            app.Theta4_Slider, app.Theta5_Slider, app.Theta6_Slider};
            numericFields = {app.Theta1_Field, app.Theta2_Field, app.Theta3_Field, ...
                             app.Theta4_Field, app.Theta5_Field, app.Theta6_Field};
            app.MultipleGUIControl(sliderFields, numericFields, [0 0 0 0 0 0]);
        end

        % Button pushed function: INVERSEKINEMATICButton
        function INVERSEKINEMATICButtonPushed(app, event)
            Xval = app.Xinput.Value;
            Yval = app.Yinput.Value;
            Zval = app.Zinput.Value;
            phi = app.Precession.Value;
            theta = app.Nutation.Value;
            psi = app.Spin.Value;
            
            app.InverseKinematic(Xval, Yval, Zval, phi, theta, psi);
            
        end

        % Button pushed function: TransferButton
        function TransferButtonPushed(app, event)
            app.Xinput.Value = app.ForwardPx.Value;
            app.Yinput.Value = app.ForwardPy.Value;
            app.Zinput.Value = app.ForwardPz.Value;
            app.Precession.Value = app.PrecessionDisp.Value;
            app.Nutation.Value = app.NutationDisp.Value;
            app.Spin.Value = app.SpinDisp.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Theta1_Slider = uislider(app.UIFigure);
            app.Theta1_Slider.Limits = [-170 170];
            app.Theta1_Slider.ValueChangingFcn = createCallbackFcn(app, @Theta1_SliderValueChanging, true);
            app.Theta1_Field = uieditfield(app.UIFigure, 'numeric');
            app.Theta1_Field.ValueChangedFcn = createCallbackFcn(app, @Theta1_FieldValueChanged, true);
            app.Theta2_Slider = uislider(app.UIFigure);
            app.Theta2_Slider.Limits = [-65 85];
            app.Theta2_Slider.ValueChangingFcn = createCallbackFcn(app, @Theta2_SliderValueChanging, true);
            app.Theta2_Field = uieditfield(app.UIFigure, 'numeric');
            app.Theta2_Field.ValueChangedFcn = createCallbackFcn(app, @Theta2_FieldValueChanged, true);
            app.Theta3_Slider = uislider(app.UIFigure);
            app.Theta3_Slider.Limits = [-180 70];
            app.Theta3_Slider.ValueChangingFcn = createCallbackFcn(app, @Theta3_SliderValueChanging, true);
            app.Theta3_Field = uieditfield(app.UIFigure, 'numeric');
            app.Theta3_Field.ValueChangedFcn = createCallbackFcn(app, @Theta3_FieldValueChanged, true);
            app.Theta4_Slider = uislider(app.UIFigure);
            app.Theta4_Slider.Limits = [-300 300];
            app.Theta4_Slider.ValueChangingFcn = createCallbackFcn(app, @Theta4_SliderValueChanging, true);
            app.Theta4_Field = uieditfield(app.UIFigure, 'numeric');
            app.Theta4_Field.ValueChangedFcn = createCallbackFcn(app, @Theta4_FieldValueChanged, true);
            app.Theta5_Slider = uislider(app.UIFigure);
            app.Theta5_Slider.Limits = [-130 130];
            app.Theta5_Slider.ValueChangingFcn = createCallbackFcn(app, @Theta5_SliderValueChanging, true);
            app.Theta5_Field = uieditfield(app.UIFigure, 'numeric');
            app.Theta5_Field.ValueChangedFcn = createCallbackFcn(app, @Theta5_FieldValueChanged, true);
            app.Theta6_Slider = uislider(app.UIFigure);
            app.Theta6_Slider.Limits = [-360 360];
            app.Theta6_Slider.ValueChangingFcn = createCallbackFcn(app, @Theta6_SliderValueChanging, true);
            app.Theta6_Field = uieditfield(app.UIFigure, 'numeric');
            app.Theta6_Field.ValueChangedFcn = createCallbackFcn(app, @Theta6_FieldValueChanged, true);
            app.ForwardPx = uieditfield(app.UIFigure, 'numeric');
            app.ForwardPy = uieditfield(app.UIFigure, 'numeric');
            app.ForwardPz = uieditfield(app.UIFigure, 'numeric');
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Theta1'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Theta2'};
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'Theta3'};
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_4.Value = {'Theta4'};
            app.TextArea_5 = uitextarea(app.UIFigure);
            app.TextArea_5.Value = {'Theta5'};
            app.TextArea_6 = uitextarea(app.UIFigure);
            app.TextArea_6.Value = {'Theta6'};
            app.TextArea_7 = uitextarea(app.UIFigure);
            app.TextArea_7.Value = {'Px'};
            app.TextArea_8 = uitextarea(app.UIFigure);
            app.TextArea_8.Value = {'Py'};
            app.TextArea_9 = uitextarea(app.UIFigure);
            app.TextArea_9.Value = {'Pz'};
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.DEFAULTTOZEROButton = uibutton(app.UIFigure, 'push');
            app.DEFAULTTOZEROButton.ButtonPushedFcn = createCallbackFcn(app, @DEFAULTTOZEROButtonPushed, true);
            app.DEFAULTTOZEROButton.Text = {'DEFAULT TO '; 'ZERO'};
            app.INVERSEKINEMATICButton = uibutton(app.UIFigure, 'push');
            app.INVERSEKINEMATICButton.ButtonPushedFcn = createCallbackFcn(app, @INVERSEKINEMATICButtonPushed, true);
            app.INVERSEKINEMATICButton.Text = {'INVERSE '; 'KINEMATIC'};
            app.Xinput = uieditfield(app.UIFigure, 'numeric');
            app.Yinput = uieditfield(app.UIFigure, 'numeric');
            app.Zinput = uieditfield(app.UIFigure, 'numeric');
            app.Precession = uieditfield(app.UIFigure, 'numeric');
            app.Nutation = uieditfield(app.UIFigure, 'numeric');
            app.Spin = uieditfield(app.UIFigure, 'numeric');
            app.TextArea_10 = uitextarea(app.UIFigure);
            app.TextArea_10.Value = {'X'};
            app.TextArea_11 = uitextarea(app.UIFigure);
            app.TextArea_11.Value = {'Y'};
            app.TextArea_12 = uitextarea(app.UIFigure);
            app.TextArea_12.Value = {'Z'};
            app.TextArea_13 = uitextarea(app.UIFigure);
            app.TextArea_13.Value = {'Precession'};
            app.TextArea_14 = uitextarea(app.UIFigure);
            app.TextArea_14.Value = {'Nutation'};
            app.TextArea_15 = uitextarea(app.UIFigure);
            app.TextArea_15.Value = {'Spin'};
            app.PrecessionDisp = uieditfield(app.UIFigure, 'numeric');
            app.NutationDisp = uieditfield(app.UIFigure, 'numeric');
            app.SpinDisp = uieditfield(app.UIFigure, 'numeric');
            app.TextArea_16 = uitextarea(app.UIFigure);
            app.TextArea_16.Value = {'Precession'};
            app.TextArea_17 = uitextarea(app.UIFigure);
            app.TextArea_17.Value = {'Nutation'};
            app.TextArea_18 = uitextarea(app.UIFigure);
            app.TextArea_18.Value = {'Spin'};
            app.TransferButton = uibutton(app.UIFigure, 'push');
            app.TransferButton.ButtonPushedFcn = createCallbackFcn(app, @TransferButtonPushed, true);
            app.TransferButton.Text = 'Transfer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IRB_6700_Controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end